classdef FilterDesign < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FrequencyradsecPanel       matlab.ui.container.Panel
        PassEditFieldLabel         matlab.ui.control.Label
        PassEditField              matlab.ui.control.NumericEditField
        Pass2EditFieldLabel        matlab.ui.control.Label
        Pass2EditField             matlab.ui.control.NumericEditField
        StopEditFieldLabel         matlab.ui.control.Label
        StopEditField              matlab.ui.control.NumericEditField
        Stop2EditFieldLabel        matlab.ui.control.Label
        Stop2EditField             matlab.ui.control.NumericEditField
        TypeButtonGroup            matlab.ui.container.ButtonGroup
        LowPassButton              matlab.ui.control.RadioButton
        HighPassButton             matlab.ui.control.RadioButton
        BandPassButton             matlab.ui.control.RadioButton
        NotchButton                matlab.ui.control.RadioButton
        ClassificationButtonGroup  matlab.ui.container.ButtonGroup
        ButterworthButton          matlab.ui.control.RadioButton
        ChebyshevButton            matlab.ui.control.RadioButton
        ParametersPanel            matlab.ui.container.Panel
        AmaxEditFieldLabel         matlab.ui.control.Label
        AmaxEditField              matlab.ui.control.NumericEditField
        AminEditFieldLabel         matlab.ui.control.Label
        AminEditField              matlab.ui.control.NumericEditField
        PolesTextAreaLabel         matlab.ui.control.Label
        PolesTextArea              matlab.ui.control.TextArea
        RunButton                  matlab.ui.control.Button
        DropDownLabel              matlab.ui.control.Label
        DropDown                   matlab.ui.control.DropDown
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('Filters')
        end

        % Selection changed function: TypeButtonGroup
        function TypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.TypeButtonGroup.SelectedObject;
            if (selectedButton == app.LowPassButton) || (selectedButton == app.HighPassButton)
                app.Pass2EditField.Visible = 'off';
                app.Stop2EditField.Visible = 'off';
            else
                app.Pass2EditField.Visible = 'on';
                app.Stop2EditField.Visible = 'on';
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if (app.DropDown.Value == 'Hz')
                wp = app.PassEditField.Value*2*pi;
                wp2 = app.Pass2EditField.Value*2*pi;
                ws = app.StopEditField.Value*2*pi;
                ws2 = app.Stop2EditField.Value*2*pi;
            else
                wp = app.PassEditField.Value;
                wp2 = app.Pass2EditField.Value;
                ws = app.StopEditField.Value;
                ws2 = app.Stop2EditField.Value;
            end
            if app.ButterworthButton.Value ==1
                if app.LowPassButton.Value ==1
                    f = Butterworth(app.AmaxEditField.Value,app.AminEditField.Value,[wp,ws],"Low");
                elseif app.HighPassButton.Value ==1
                    f = Butterworth(app.AmaxEditField.Value,app.AminEditField.Value,[wp,ws],"High");
                elseif app.BandPassButton.Value ==1
                    f = Butterworth(app.AmaxEditField.Value,app.AminEditField.Value,[wp,wp2,ws,ws2],"Band");
                elseif app.NotchButton.Value ==1
                    f = Butterworth(app.AmaxEditField.Value,app.AminEditField.Value,[wp,wp2,ws,ws2],"Notch");
                end
            elseif app.ChebyshevButton.Value ==1
                if app.LowPassButton.Value ==1
                    f = Chebyshev(app.AmaxEditField.Value,app.AminEditField.Value,[wp,ws],"Low");
                elseif app.HighPassButton.Value ==1
                    f = Chebyshev(app.AmaxEditField.Value,app.AminEditField.Value,[wp,ws],"High");
                elseif app.BandPassButton.Value ==1
                    f = Chebyshev(app.AmaxEditField.Value,app.AminEditField.Value,[wp,wp2,ws,ws2],"Band");
                elseif app.NotchButton.Value ==1
                    f = Chebyshev(app.AmaxEditField.Value,app.AminEditField.Value,[wp,wp2,ws,ws2],"Notch");
                end
            end
            
            f.poles = round(f.poles,3);
            polesstring = zeros(length(f.poles));
            for k = 1:length(f.poles)
                polesstring(k) = 'Pole ' + num2str(k) + ': '+ num2str(f.poles(k));
            end
            app.PolesTextArea.Value = polesstring;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FrequencyradsecPanel = uipanel(app.UIFigure);
            app.FrequencyradsecPanel.Title = 'Frequency (rad/sec)';
            app.PassEditFieldLabel = uilabel(app.FrequencyradsecPanel);
            app.PassEditFieldLabel.Text = 'Pass';
            app.PassEditField = uieditfield(app.FrequencyradsecPanel, 'numeric');
            app.Pass2EditFieldLabel = uilabel(app.FrequencyradsecPanel);
            app.Pass2EditFieldLabel.Text = 'Pass2';
            app.Pass2EditField = uieditfield(app.FrequencyradsecPanel, 'numeric');
            app.StopEditFieldLabel = uilabel(app.FrequencyradsecPanel);
            app.StopEditFieldLabel.Text = 'Stop';
            app.StopEditField = uieditfield(app.FrequencyradsecPanel, 'numeric');
            app.Stop2EditFieldLabel = uilabel(app.FrequencyradsecPanel);
            app.Stop2EditFieldLabel.Text = 'Stop2';
            app.Stop2EditField = uieditfield(app.FrequencyradsecPanel, 'numeric');
            app.TypeButtonGroup = uibuttongroup(app.UIFigure);
            app.TypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TypeButtonGroupSelectionChanged, true);
            app.TypeButtonGroup.Title = 'Type';
            app.LowPassButton = uiradiobutton(app.TypeButtonGroup);
            app.LowPassButton.Text = 'LowPass';
            app.LowPassButton.Value = true;
            app.HighPassButton = uiradiobutton(app.TypeButtonGroup);
            app.HighPassButton.Text = 'HighPass';
            app.BandPassButton = uiradiobutton(app.TypeButtonGroup);
            app.BandPassButton.Text = 'BandPass';
            app.NotchButton = uiradiobutton(app.TypeButtonGroup);
            app.NotchButton.Text = 'Notch';
            app.ClassificationButtonGroup = uibuttongroup(app.UIFigure);
            app.ClassificationButtonGroup.Title = 'Classification';
            app.ButterworthButton = uiradiobutton(app.ClassificationButtonGroup);
            app.ButterworthButton.Text = 'Butterworth';
            app.ButterworthButton.Value = true;
            app.ChebyshevButton = uiradiobutton(app.ClassificationButtonGroup);
            app.ChebyshevButton.Text = 'Chebyshev';
            app.ParametersPanel = uipanel(app.UIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.AmaxEditFieldLabel = uilabel(app.ParametersPanel);
            app.AmaxEditFieldLabel.Text = 'Amax';
            app.AmaxEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.AminEditFieldLabel = uilabel(app.ParametersPanel);
            app.AminEditFieldLabel.Text = 'Amin';
            app.AminEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.PolesTextAreaLabel = uilabel(app.UIFigure);
            app.PolesTextAreaLabel.Text = 'Poles';
            app.PolesTextArea = uitextarea(app.UIFigure);
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Hz', 'Rad/sec'};
            app.DropDown.Value = 'Hz';
        end
    end

    methods (Access = public)

        % Construct app
        function app = FilterDesign

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end